classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GenerujdaneButton             matlab.ui.control.Button
        MetodaDropDownLabel           matlab.ui.control.Label
        MetodaDropDown                matlab.ui.control.DropDown
        InputDataTable                matlab.ui.control.Table
        OutputDataTable               matlab.ui.control.Table
        LiczbakryteriumDropDownLabel  matlab.ui.control.Label
        LiczbakryteriumDropDown       matlab.ui.control.DropDown
        WczytajdaneButton             matlab.ui.control.Button
        RozmiarprbkiEditFieldLabel    matlab.ui.control.Label
        RozmiarProbkiEditField        matlab.ui.control.NumericEditField
        RankingButton                 matlab.ui.control.Button
        RozkadDropDown                matlab.ui.control.DropDown
        RozkadDropDownLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        algos = containers.Map();
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MetodaDropDown.Items = app.algos.keys();
        end

        % Button pushed function: GenerujdaneButton
        function GenerujdaneButtonPushed(app, event)
            distribution = app.RozkadDropDown.Value;
            no_samples = app.RozmiarProbkiEditField.Value;
            no_criterion = str2num(app.LiczbakryteriumDropDown.Value);
            switch distribution
                case 'Normalny'
                    prompt = {'Parameter mu:', 'Parameter sigma:'};
                    dlgtitle = 'Input';
                    dims = [1 35];
                    definput = {'1', '0'};
                    answer = inputdlg(prompt, dlgtitle, dims,definput);
                    mu = str2double(answer{1});
                    sigma = str2double(answer{2});
                    map = containers.Map({'mu', 'sigma'}, [mu, sigma]);
                    generate_data('Normalny', no_samples, no_criterion, map);
                case 'Eksponencjalny'
                    prompt = {'Parameter mu:'};
                    dlgtitle = 'Input';
                    dims = [1 35];
                    definput = {'1'};
                    answer = inputdlg(prompt, dlgtitle, dims,definput);
                    mu = str2double(answer{1});
                    map = containers.Map({'mu'}, [mu]);
                    generate_data('Eksponencjalny', no_samples, no_criterion, map);
                case 'Jednostajny'
                    generate_data('Jednostajny', no_samples, no_criterion);
            end
            
        end

        % Button pushed function: RankingButton
        function RankingButtonPushed(app, event)
            algo_name = app.MetodaDropDown.Value;
            algo = app.algos(algo_name);
            dlgtitle = algo_name + " params";
            dims = [1 40];
            value = inputdlg(algo.Params.keys(),dlgtitle,dims,algo.Params.values());
            algo = algo.init(containers.Map(algo.Params.keys(), value));
            % tutaj zostaje zwrócona poprawnie zainicjalizowany algorytm
            % result = algo.calculate(data)
            % result stanowi wyjście z algorytmu
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GenerujdaneButton = uibutton(app.UIFigure, 'push');
            app.GenerujdaneButton.ButtonPushedFcn = createCallbackFcn(app, @GenerujdaneButtonPushed, true);
            app.GenerujdaneButton.Text = 'Generuj dane';
            app.MetodaDropDownLabel = uilabel(app.UIFigure);
            app.MetodaDropDownLabel.Text = 'Metoda';
            app.MetodaDropDown = uidropdown(app.UIFigure);
            app.InputDataTable = uitable(app.UIFigure);
            app.InputDataTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.InputDataTable.RowName = {};
            app.OutputDataTable = uitable(app.UIFigure);
            app.OutputDataTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.OutputDataTable.RowName = {};
            app.LiczbakryteriumDropDownLabel = uilabel(app.UIFigure);
            app.LiczbakryteriumDropDownLabel.Text = 'Liczba kryterium';
            app.LiczbakryteriumDropDown = uidropdown(app.UIFigure);
            app.LiczbakryteriumDropDown.Items = {'2', '3', '4', '5', '10'};
            app.LiczbakryteriumDropDown.Value = '2';
            app.WczytajdaneButton = uibutton(app.UIFigure, 'push');
            app.WczytajdaneButton.Text = {'Wczytaj'; 'dane'};
            app.RozmiarprbkiEditFieldLabel = uilabel(app.UIFigure);
            app.RozmiarprbkiEditFieldLabel.Text = {'Rozmiar'; 'próbki'};
            app.RozmiarProbkiEditField = uieditfield(app.UIFigure, 'numeric');
            app.RankingButton = uibutton(app.UIFigure, 'push');
            app.RankingButton.ButtonPushedFcn = createCallbackFcn(app, @RankingButtonPushed, true);
            app.RankingButton.Text = 'Ranking';
            app.RozkadDropDown = uidropdown(app.UIFigure);
            app.RozkadDropDown.Items = {'Normalny', 'Eksponencjalny', 'Jednostajny'};
            app.RozkadDropDown.Value = 'Normalny';
            app.RozkadDropDownLabel = uilabel(app.UIFigure);
            app.RozkadDropDownLabel.Text = 'Rozkład';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end